classdef mEMbrain_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        mEMbrainUIFigure               matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        NeurontoBorderTab              matlab.ui.container.Tab
        ConvertNeuronGroundTruthDatatoBorderGroundTruthDataLabel  matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        NeuronGroundTruthLabel         matlab.ui.control.Label
        SelectInputDirectoryButton     matlab.ui.control.Button
        userSelectedInputDirectoryLabel  matlab.ui.control.Label
        BorderGroundTruthLabel         matlab.ui.control.Label
        SelectOutputDirectoryButton    matlab.ui.control.Button
        BorderPropertiesLabel          matlab.ui.control.Label
        SetborderpixelstoButtonGroup   matlab.ui.container.ButtonGroup
        zeroButton                     matlab.ui.control.ToggleButton
        oneButton                      matlab.ui.control.ToggleButton
        SetborderthicknesstoSpinnerLabel  matlab.ui.control.Label
        borderThicknessSpinner         matlab.ui.control.Spinner
        BorderGroundTruthPreviewLabel  matlab.ui.control.Label
        SelectedInputDirectoryLabel    matlab.ui.control.Label
        userSelectedOutputDirectoryLabel  matlab.ui.control.Label
        SelectedOutputDirectoryLabel   matlab.ui.control.Label
        ConvertButton                  matlab.ui.control.Button
        BorderPreviewAxes              matlab.ui.control.UIAxes
        ResetButton                    matlab.ui.control.Button
        FillExtracellularSpaceECSSwitchLabel  matlab.ui.control.Label
        FillExtracellularSpaceECSSwitch  matlab.ui.control.Switch
        NewProjectTab                  matlab.ui.container.Tab
        FirstTabButton                 matlab.ui.control.StateButton
        SwitchLabel                    matlab.ui.control.Label
        Switch                         matlab.ui.control.RockerSwitch
        KnobLabel                      matlab.ui.control.Label
        Knob                           matlab.ui.control.Knob
        PickUpaProjectTab              matlab.ui.container.Tab
        SecondTabButton                matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        inputDirectory % Neuron GT input directory (set in SelectInputDirectoryButtonPushed)
        outputDirectory % Border GT output directory (set in SelectOutputDirectoryButtonPushed)
        borderValue % Pixel value assigned to borders (0 or 1)
        borderThickness % Size of erosion/dilation structure element neighborhood (the "thickness" of the borders)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            title(app.BorderPreviewAxes, []);
            xlabel(app.BorderPreviewAxes, []);
            ylabel(app.BorderPreviewAxes, []);
            app.BorderPreviewAxes.XAxis.TickLabels = {};
            app.BorderPreviewAxes.YAxis.TickLabels = {};
        end

        % Button pushed function: SelectInputDirectoryButton
        function SelectInputDirectoryButtonPushed(app, event)
            % When the "Select Input Directory" button is pushed, the user
            % selects a directory for the neuron GT (TODO: expand to
            % reverse problem?) whose images will be processed when the
            % "Convert" button is pressed.
            
            selectInputDirectory = uigetdir;
            drawnow;
            figure(app.mEMbrainUIFigure) % Brings mEMbrain app back into focus
            if selectInputDirectory ~= 0
                app.inputDirectory = selectInputDirectory;
                app.userSelectedInputDirectoryLabel.Text = app.inputDirectory;
            end
            
            % Check if both input directory and output directory have been
            % specified. If yes, then enable the "Convert" button
            
            if ~isempty(app.inputDirectory) && ~isempty(app.outputDirectory)
                app.ConvertButton.Enable = 'on';
            end
            
        end

        % Button pushed function: SelectOutputDirectoryButton
        function SelectOutputDirectoryButtonPushed(app, event)
            % When the "Select Output Directory" button is pushed, the user
            % selects a directory for the border GT (TODO: expand to
            % reverse problem?) where images will be saved when the
            % "Convert" button is pressed.
            
            selectOutputDirectory = uigetdir;
            drawnow;
            figure(app.mEMbrainUIFigure) % Brings mEMbrain app back into focus
            if selectOutputDirectory ~= 0
                app.outputDirectory = selectOutputDirectory;
                app.userSelectedOutputDirectoryLabel.Text = app.outputDirectory;
            end
            
             % Check if both input directory and output directory have been
            % specified. If yes, then enable the "Convert" button
            
            if ~isempty(app.inputDirectory) && ~isempty(app.outputDirectory)
                app.ConvertButton.Enable = 'on';
            end
            
        end

        % Button pushed function: ConvertButton
        function ConvertButtonPushed(app, event)
            % When the "Convert" button is pushed, the app reads the values
            % of the "Set Border Pixels To..." button group and the "Border
            % Thickness" spinner, reads the images in the input directory,
            % processes them with erosion/dilation, and saves them to the
            % output directory
             
            % Check which toggle button for the value of border pixels is
            % selected
            switch app.zeroButton.Value
                case 0
                    app.borderValue = 1;
                case 1
                    app.borderValue = 0;
                otherwise
                    app.borderValue = 1;
            end
            
            % Get value of border thickness spinner
            app.borderThickness = app.borderThicknessSpinner.Value;
            
            % Read input directory images (PNG format)
            inputFilePattern = fullfile(app.inputDirectory, '*.png');
            neuronGTFiles = dir(inputFilePattern);
            nFiles = length(neuronGTFiles);
            
            % Open a progress bar
            fig = uifigure;
            progressBar = uiprogressdlg(fig, 'Title', 'Please Wait', 'Message', 'Reading neuron ground truth images');
            
            for imgIndex = 1:nFiles
                currentfilename = neuronGTFiles(imgIndex).name;
                fullfilename = fullfile(app.inputDirectory, currentfilename);
                currentimage = imread(fullfilename);
                neuronGT{imgIndex} = currentimage;
                progressBar.Value = imgIndex/(2*nFiles); % Update the value of the progress bar (finishing the Neuron GT is halfway)
            end
            
            % Convert each neuron GT image into border GT image and save it
            % to the output directory
            
            progressBar.Message = "Converting neuron ground truth to border ground truth";
            
            for imgIndex = 1:nFiles
                currentNeuronGT = neuronGT{imgIndex};
                grayNeuronGT = unique_rgb(currentNeuronGT); % Convert RGB image into grayscale image
                structuringElement = ones(app.borderThickness, app.borderThickness);
                newBorderGT = imerode(grayNeuronGT, structuringElement) ~= imdilate(grayNeuronGT, structuringElement); % Conversion to border GT
                
                % Fill in ECS if indicated
                if app.FillExtracellularSpaceECSSwitch.Value == "On"
                    newBorderGT(grayNeuronGT == 0) = 1;                    
                end
                
                switch app.borderValue
                    case 0
                        newBorderGT = ~newBorderGT;
                    otherwise
                        % Do nothing; border value is already 1.
                end
                
                % Show the first border GT in the preview axes                
                if imgIndex == 1
                    app.BorderPreviewAxes.Visible = 'on';
                    imshow(newBorderGT, 'parent', app.BorderPreviewAxes)
                end
                
                borderFileName = strcat(erase(neuronGTFiles(imgIndex).name, ".png"), "_border.png");
                borderFullFileName = fullfile(app.outputDirectory, borderFileName);
                imwrite(newBorderGT, borderFullFileName);
                progressBar.Value = 0.5 + imgIndex/(2*nFiles);
            end
            
            close(progressBar)
            close(fig)
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % When the "Reset" button is pushed, all app properties should
            % be emptied, Border Preview Axes are cleared and made
            % invisible, and "Convert" button is disabled
            
            app.inputDirectory = [];
            app.outputDirectory = [];
            app.borderValue = [];
            app.borderThickness = [];
            
            app.userSelectedInputDirectoryLabel.Text = "No Directory Selected";
            app.userSelectedOutputDirectoryLabel.Text = "No Directory Selected";
            
            cla(app.BorderPreviewAxes)
            app.BorderPreviewAxes.Visible = "off";
            
            app.ConvertButton.Enable = "off";
            
            app.borderThicknessSpinner.Value = 3;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.mEMbrainUIFigure = uifigure('Visible', 'off');
            app.mEMbrainUIFigure.Name = 'mEMbrain';
            app.TabGroup = uitabgroup(app.mEMbrainUIFigure);
            app.NeurontoBorderTab = uitab(app.TabGroup);
            app.NeurontoBorderTab.Title = 'Neuron to Border';
            app.ConvertNeuronGroundTruthDatatoBorderGroundTruthDataLabel = uilabel(app.NeurontoBorderTab);
            app.ConvertNeuronGroundTruthDatatoBorderGroundTruthDataLabel.Text = 'Convert Neuron Ground Truth Data to Border Ground Truth Data';
            app.Label = uilabel(app.NeurontoBorderTab);
            app.Label.Text = 'Please select the ground truth directory to convert the neuron data to border data';
            app.NeuronGroundTruthLabel = uilabel(app.NeurontoBorderTab);
            app.NeuronGroundTruthLabel.Text = 'Neuron Ground Truth';
            app.SelectInputDirectoryButton = uibutton(app.NeurontoBorderTab, 'push');
            app.SelectInputDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectInputDirectoryButtonPushed, true);
            app.SelectInputDirectoryButton.Text = 'Select Input Directory...';
            app.userSelectedInputDirectoryLabel = uilabel(app.NeurontoBorderTab);
            app.userSelectedInputDirectoryLabel.Text = 'No Directory Selected';
            app.BorderGroundTruthLabel = uilabel(app.NeurontoBorderTab);
            app.BorderGroundTruthLabel.Text = 'Border Ground Truth';
            app.SelectOutputDirectoryButton = uibutton(app.NeurontoBorderTab, 'push');
            app.SelectOutputDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectOutputDirectoryButtonPushed, true);
            app.SelectOutputDirectoryButton.Text = 'Select Output Directory...';
            app.BorderPropertiesLabel = uilabel(app.NeurontoBorderTab);
            app.BorderPropertiesLabel.Text = 'Border Properties';
            app.SetborderpixelstoButtonGroup = uibuttongroup(app.NeurontoBorderTab);
            app.SetborderpixelstoButtonGroup.Title = 'Set border pixels to...';
            app.zeroButton = uitogglebutton(app.SetborderpixelstoButtonGroup);
            app.zeroButton.Text = '0';
            app.oneButton = uitogglebutton(app.SetborderpixelstoButtonGroup);
            app.oneButton.Text = '1';
            app.oneButton.Value = true;
            app.SetborderthicknesstoSpinnerLabel = uilabel(app.NeurontoBorderTab);
            app.SetborderthicknesstoSpinnerLabel.Text = 'Set border thickness to...';
            app.borderThicknessSpinner = uispinner(app.NeurontoBorderTab);
            app.borderThicknessSpinner.Limits = [1 Inf];
            app.borderThicknessSpinner.Value = 3;
            app.BorderGroundTruthPreviewLabel = uilabel(app.NeurontoBorderTab);
            app.BorderGroundTruthPreviewLabel.Text = 'Border Ground Truth Preview';
            app.SelectedInputDirectoryLabel = uilabel(app.NeurontoBorderTab);
            app.SelectedInputDirectoryLabel.Text = 'Selected Directory: ';
            app.userSelectedOutputDirectoryLabel = uilabel(app.NeurontoBorderTab);
            app.userSelectedOutputDirectoryLabel.Text = 'No Directory Selected';
            app.SelectedOutputDirectoryLabel = uilabel(app.NeurontoBorderTab);
            app.SelectedOutputDirectoryLabel.Text = 'Selected Directory: ';
            app.ConvertButton = uibutton(app.NeurontoBorderTab, 'push');
            app.ConvertButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertButtonPushed, true);
            app.ConvertButton.Enable = 'off';
            app.ConvertButton.Text = 'Convert';
            app.ResetButton = uibutton(app.NeurontoBorderTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.BorderPreviewAxes = uiaxes(app.NeurontoBorderTab);
            title(app.BorderPreviewAxes, 'Title')
            xlabel(app.BorderPreviewAxes, 'X')
            ylabel(app.BorderPreviewAxes, 'Y')
            app.BorderPreviewAxes.Visible = 'off';
            app.FillExtracellularSpaceECSSwitchLabel = uilabel(app.NeurontoBorderTab);
            app.FillExtracellularSpaceECSSwitchLabel.Text = 'Fill Extracellular-Space (ECS)';
            app.FillExtracellularSpaceECSSwitch = uiswitch(app.NeurontoBorderTab, 'slider');
            app.NewProjectTab = uitab(app.TabGroup);
            app.NewProjectTab.Title = 'New Project';
            app.FirstTabButton = uibutton(app.NewProjectTab, 'state');
            app.FirstTabButton.Text = 'First Tab';
            app.SwitchLabel = uilabel(app.NewProjectTab);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.NewProjectTab, 'rocker');
            app.KnobLabel = uilabel(app.NewProjectTab);
            app.KnobLabel.Text = 'Knob';
            app.Knob = uiknob(app.NewProjectTab, 'continuous');
            app.PickUpaProjectTab = uitab(app.TabGroup);
            app.PickUpaProjectTab.Title = 'Pick Up a Project';
            app.SecondTabButton = uibutton(app.PickUpaProjectTab, 'state');
            app.SecondTabButton.Text = 'Second Tab';
            % Show the figure after all components are created
            app.mEMbrainUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mEMbrain_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.mEMbrainUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.mEMbrainUIFigure)
        end
    end
end